classdef ICPro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SampletypeSwitchLabel  matlab.ui.control.Label
        Switch_SType           matlab.ui.control.Switch
        Panel_TM               matlab.ui.container.Panel
        Button_RawFile         matlab.ui.control.Button
        Field_RawFile          matlab.ui.control.EditField
        Field_SampleID         matlab.ui.control.EditField
        Field_BlankID          matlab.ui.control.EditField
        Field_SpikeID          matlab.ui.control.EditField
        Label_SampleID         matlab.ui.control.Label
        Label_BlankID          matlab.ui.control.Label
        Label_SpikeID          matlab.ui.control.Label
        Field_RinseID          matlab.ui.control.EditField
        Label_RinseID          matlab.ui.control.Label
        Field_QCID             matlab.ui.control.EditField
        Label_QCID             matlab.ui.control.Label
        Panel_TMCorr           matlab.ui.container.Panel
        Check_RC               matlab.ui.control.CheckBox
        Check_QC               matlab.ui.control.CheckBox
        Check_SC               matlab.ui.control.CheckBox
        Check_OC               matlab.ui.control.CheckBox
        Check_BC               matlab.ui.control.CheckBox
        Check_DC               matlab.ui.control.CheckBox
        Check_ExecTM           matlab.ui.control.CheckBox
        Panel_ThU              matlab.ui.container.Panel
        Button_RawFolder       matlab.ui.control.Button
        Field_RawFolder        matlab.ui.control.EditField
        Field_NBlocks          matlab.ui.control.EditField
        Field_NSamples         matlab.ui.control.EditField
        Label_NBlocks          matlab.ui.control.Label
        Label_NSamples         matlab.ui.control.Label
        Field_ICPBlankID       matlab.ui.control.EditField
        Label_ICBlankID        matlab.ui.control.Label
        Field_ICPNatUID        matlab.ui.control.EditField
        Label_ICPNatUID        matlab.ui.control.Label
        Check_ExecThU          matlab.ui.control.CheckBox
        Panel_Weights          matlab.ui.container.Panel
        TextArea               matlab.ui.control.TextArea
        SedimentLabel          matlab.ui.control.Label
        TextArea_2             matlab.ui.control.TextArea
        TextArea_3             matlab.ui.control.TextArea
        TextArea_4             matlab.ui.control.TextArea
        TextArea_5             matlab.ui.control.TextArea
        mlLabel                matlab.ui.control.Label
        mlLabel_2              matlab.ui.control.Label
        ulLabel                matlab.ui.control.Label
        ThBCLabel              matlab.ui.control.Label
        TextArea_6             matlab.ui.control.TextArea
        TextArea_7             matlab.ui.control.TextArea
        UBCLabel               matlab.ui.control.Label
        ThSCLabel              matlab.ui.control.Label
        TextArea_8             matlab.ui.control.TextArea
        USCLabel               matlab.ui.control.Label
        TextArea_9             matlab.ui.control.TextArea
        IDLabel                matlab.ui.control.Label
        ContextMenu            matlab.ui.container.ContextMenu
        Menu                   matlab.ui.container.Menu
        Menu_2                 matlab.ui.container.Menu
        Menu2                  matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_RawFolder
        function Browse(app, event)
            uigetdir
        end

        % Value changed function: Check_ExecTM
        function TMtoggle(app, event)
            value = app.Check_ExecTM.Value;
            if value == 0
                % Disable entire TM field
                set(app.Field_RawFile,'Enable','off')
                set(app.Button_RawFile,'Enable','off')
                set(app.Field_SampleID,'Enable','off')
                set(app.Field_BlankID,'Enable','off')
                set(app.Field_SpikeID,'Enable','off')
                set(app.Field_RinseID,'Enable','off')
                set(app.Field_QCID,'Enable','off')
                set(app.Label_SampleID,'FontColor',[.7 .7 .7])
                set(app.Label_BlankID,'FontColor',[.7 .7 .7])
                set(app.Label_SpikeID,'FontColor',[.7 .7 .7])
                set(app.Label_RinseID,'FontColor',[.7 .7 .7])
                set(app.Label_QCID,'FontColor',[.7 .7 .7])
                set(app.Check_RC,'Enable','off')
                set(app.Check_QC,'Enable','off')
                set(app.Check_SC,'Enable','off')
                set(app.Check_OC,'Enable','off')
                set(app.Check_BC,'Enable','off')
                set(app.Check_DC,'Enable','off')
                % Disable selected weight input fields
                if app.Check_ExecThU.Value == 0
                    set(app.TextArea,'Enable','off')
                    set(app.TextArea_2,'Enable','off')
                    set(app.TextArea_3,'Enable','off')
                    set(app.TextArea_4,'Enable','off')
                    set(app.TextArea_5,'Enable','off')
                    set(app.TextArea_6,'Enable','off')
                    set(app.TextArea_7,'Enable','off')
                    set(app.TextArea_8,'Enable','off')
                    set(app.TextArea_9,'Enable','off')
                end
            else
                % Enable entire TM field
                set(app.Field_RawFile,'Enable','on')
                set(app.Button_RawFile,'Enable','on')
                set(app.Field_SampleID,'Enable','on')
                set(app.Field_BlankID,'Enable','on')
                set(app.Field_SpikeID,'Enable','on')
                set(app.Field_RinseID,'Enable','on')
                set(app.Field_QCID,'Enable','on')
                set(app.Label_SampleID,'FontColor',[0 0 0])
                set(app.Label_BlankID,'FontColor',[0 0 0])
                set(app.Label_SpikeID,'FontColor',[0 0 0])
                set(app.Label_RinseID,'FontColor',[0 0 0])
                set(app.Label_QCID,'FontColor',[0 0 0])
                set(app.Check_RC,'Enable','on')
                set(app.Check_QC,'Enable','on')
                set(app.Check_SC,'Enable','on')
                set(app.Check_OC,'Enable','on')
                set(app.Check_BC,'Enable','on')
                set(app.Check_DC,'Enable','on')
                % Enable selected weight input fields
                if app.Check_ExecThU.Value == 0
                    set(app.TextArea,'Enable','on')
                    set(app.TextArea_2,'Enable','on')
                    set(app.TextArea_3,'Enable','on')
                    set(app.TextArea_4,'Enable','on')
                    set(app.TextArea_6,'Enable','on')
                    set(app.TextArea_7,'Enable','on')
                    set(app.TextArea_8,'Enable','on')
                    set(app.TextArea_9,'Enable','on')
                end
            end
        end

        % Value changed function: Check_ExecThU
        function ThUtoggle(app, event)
            value = app.Check_ExecThU.Value;
            if value == 0
                set(app.Field_RawFolder,'Enable','off')
                set(app.Button_RawFolder,'Enable','off')
                set(app.Field_NBlocks,'Enable','off')
                set(app.Field_NSamples,'Enable','off')
                set(app.Field_ICPBlankID,'Enable','off')
                set(app.Field_ICPNatUID,'Enable','off')
                set(app.Label_NBlocks,'FontColor',[.7 .7 .7])
                set(app.Label_NSamples,'FontColor',[.7 .7 .7])
                set(app.Label_ICBlankID,'FontColor',[.7 .7 .7])
                set(app.Label_ICPNatUID,'FontColor',[.7 .7 .7])
                if app.Check_ExecTM.Value == 0
                    set(app.TextArea,'Enable','off')
                    set(app.TextArea_2,'Enable','off')
                    set(app.TextArea_3,'Enable','off')
                    set(app.TextArea_4,'Enable','off')
                    set(app.TextArea_6,'Enable','off')
                    set(app.TextArea_9,'Enable','off')
                end
            else
                set(app.Field_RawFolder,'Enable','on')
                set(app.Button_RawFolder,'Enable','on')
                set(app.Field_NBlocks,'Enable','on')
                set(app.Field_NSamples,'Enable','on')
                set(app.Field_ICPBlankID,'Enable','on')
                set(app.Field_ICPNatUID,'Enable','on')
                set(app.Label_NBlocks,'FontColor',[0 0 0])
                set(app.Label_NSamples,'FontColor',[0 0 0])
                set(app.Label_ICBlankID,'FontColor',[0 0 0])
                set(app.Label_ICPNatUID,'FontColor',[0 0 0])
                if app.Check_ExecTM.Value == 0
                    set(app.TextArea,'Enable','on')
                    set(app.TextArea_2,'Enable','on')
                    set(app.TextArea_3,'Enable','on')
                    set(app.TextArea_4,'Enable','on')
                    set(app.TextArea_6,'Enable','on')
                    set(app.TextArea_9,'Enable','on')
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SampletypeSwitchLabel = uilabel(app.UIFigure);
            app.SampletypeSwitchLabel.Text = 'Sample type';
            app.Switch_SType = uiswitch(app.UIFigure, 'slider');
            app.Switch_SType.Items = {'Sediment', 'Sea water'};
            app.Switch_SType.Value = 'Sediment';
            app.Panel_TM = uipanel(app.UIFigure);
            app.Panel_TM.Title = 'Trace metal data processing';
            app.Button_RawFile = uibutton(app.Panel_TM, 'push');
            app.Button_RawFile.Text = '...';
            app.Field_RawFile = uieditfield(app.Panel_TM, 'text');
            app.Field_RawFile.Value = 'Trace metal raw data file';
            app.Field_SampleID = uieditfield(app.Panel_TM, 'text');
            app.Field_SampleID.Value = 'JW';
            app.Field_BlankID = uieditfield(app.Panel_TM, 'text');
            app.Field_BlankID.Value = 'blank';
            app.Field_SpikeID = uieditfield(app.Panel_TM, 'text');
            app.Field_SpikeID.Value = 'spike';
            app.Label_SampleID = uilabel(app.Panel_TM);
            app.Label_SampleID.Text = 'Sample ID initials';
            app.Label_BlankID = uilabel(app.Panel_TM);
            app.Label_BlankID.Text = 'Procedural blank ID';
            app.Label_SpikeID = uilabel(app.Panel_TM);
            app.Label_SpikeID.Text = 'Spiked sample ID';
            app.Field_RinseID = uieditfield(app.Panel_TM, 'text');
            app.Field_RinseID.Value = 'Rinse';
            app.Label_RinseID = uilabel(app.Panel_TM);
            app.Label_RinseID.Text = 'Rinse ID';
            app.Field_QCID = uieditfield(app.Panel_TM, 'text');
            app.Field_QCID.Value = 'QC';
            app.Label_QCID = uilabel(app.Panel_TM);
            app.Label_QCID.Text = 'QC ID';
            app.Panel_TMCorr = uipanel(app.Panel_TM);
            app.Panel_TMCorr.Title = 'Select corrections to apply';
            app.Check_RC = uicheckbox(app.Panel_TMCorr);
            app.Check_RC.Text = 'Rinse correction';
            app.Check_QC = uicheckbox(app.Panel_TMCorr);
            app.Check_QC.Text = 'QC correction';
            app.Check_QC.Value = true;
            app.Check_SC = uicheckbox(app.Panel_TMCorr);
            app.Check_SC.Text = 'Spike correction';
            app.Check_OC = uicheckbox(app.Panel_TMCorr);
            app.Check_OC.Text = 'Oxide correction';
            app.Check_BC = uicheckbox(app.Panel_TMCorr);
            app.Check_BC.Text = 'Blank correction';
            app.Check_BC.Value = true;
            app.Check_DC = uicheckbox(app.Panel_TMCorr);
            app.Check_DC.Text = 'Dilution correction';
            app.Check_DC.Value = true;
            app.Check_ExecTM = uicheckbox(app.Panel_TM);
            app.Check_ExecTM.ValueChangedFcn = createCallbackFcn(app, @TMtoggle, true);
            app.Check_ExecTM.Text = 'Execute';
            app.Check_ExecTM.Value = true;
            app.Panel_ThU = uipanel(app.UIFigure);
            app.Panel_ThU.Title = 'Th/U data processing';
            app.Button_RawFolder = uibutton(app.Panel_ThU, 'push');
            app.Button_RawFolder.ButtonPushedFcn = createCallbackFcn(app, @Browse, true);
            app.Button_RawFolder.Text = '...';
            app.Field_RawFolder = uieditfield(app.Panel_ThU, 'text');
            app.Field_RawFolder.Value = 'Th/U raw data folder';
            app.Field_NBlocks = uieditfield(app.Panel_ThU, 'text');
            app.Field_NBlocks.Value = '8';
            app.Field_NSamples = uieditfield(app.Panel_ThU, 'text');
            app.Field_NSamples.Value = '3';
            app.Label_NBlocks = uilabel(app.Panel_ThU);
            app.Label_NBlocks.Text = 'No. of analysis blocks';
            app.Label_NSamples = uilabel(app.Panel_ThU);
            app.Label_NSamples.Text = 'No. of samples per block';
            app.Field_ICPBlankID = uieditfield(app.Panel_ThU, 'text');
            app.Field_ICPBlankID.Value = 'Blank';
            app.Label_ICBlankID = uilabel(app.Panel_ThU);
            app.Label_ICBlankID.Text = 'ICP Blank ID';
            app.Field_ICPNatUID = uieditfield(app.Panel_ThU, 'text');
            app.Field_ICPNatUID.Value = 'NatU';
            app.Label_ICPNatUID = uilabel(app.Panel_ThU);
            app.Label_ICPNatUID.Text = 'ICP NatU ID';
            app.Check_ExecThU = uicheckbox(app.Panel_ThU);
            app.Check_ExecThU.ValueChangedFcn = createCallbackFcn(app, @ThUtoggle, true);
            app.Check_ExecThU.Text = 'Execute';
            app.Check_ExecThU.Value = true;
            app.Panel_Weights = uipanel(app.UIFigure);
            app.Panel_Weights.Title = 'Weights [mg]';
            app.TextArea = uitextarea(app.Panel_Weights);
            app.SedimentLabel = uilabel(app.Panel_Weights);
            app.SedimentLabel.Text = 'Sediment';
            app.TextArea_2 = uitextarea(app.Panel_Weights);
            app.TextArea_3 = uitextarea(app.Panel_Weights);
            app.TextArea_4 = uitextarea(app.Panel_Weights);
            app.TextArea_5 = uitextarea(app.Panel_Weights);
            app.mlLabel = uilabel(app.Panel_Weights);
            app.mlLabel.Text = '4 ml';
            app.mlLabel_2 = uilabel(app.Panel_Weights);
            app.mlLabel_2.Text = '10 ml';
            app.ulLabel = uilabel(app.Panel_Weights);
            app.ulLabel.Text = '400 ul';
            app.ThBCLabel = uilabel(app.Panel_Weights);
            app.ThBCLabel.Text = '229Th (BC)';
            app.TextArea_6 = uitextarea(app.Panel_Weights);
            app.TextArea_7 = uitextarea(app.Panel_Weights);
            app.UBCLabel = uilabel(app.Panel_Weights);
            app.UBCLabel.Text = '236U (BC)';
            app.ThSCLabel = uilabel(app.Panel_Weights);
            app.ThSCLabel.Text = '230Th (SC)';
            app.TextArea_8 = uitextarea(app.Panel_Weights);
            app.USCLabel = uilabel(app.Panel_Weights);
            app.USCLabel.Text = '236U (SC)';
            app.TextArea_9 = uitextarea(app.Panel_Weights);
            app.TextArea_9.Editable = 'off';
            app.IDLabel = uilabel(app.Panel_Weights);
            app.IDLabel.Text = 'ID';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.SedimentLabel.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICPro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end